classdef FilterIdentificationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FilteridentificationappUIFigure  matlab.ui.Figure
        LoadnoisefileButton           matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Hyperlink                     matlab.ui.control.Hyperlink
        FilterlengthEditFieldLabel    matlab.ui.control.Label
        FilteridentificationusingNormalizedLeastSquaresAlgorithmLabel  matlab.ui.control.Label
        FilterlengthEditField         matlab.ui.control.NumericEditField
        RegularizationEditField       matlab.ui.control.NumericEditField
        RegularizationEditFieldLabel  matlab.ui.control.Label
        StepsizeEditField             matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel        matlab.ui.control.Label
        SettingsButtonGroup           matlab.ui.container.ButtonGroup
        CustomButton                  matlab.ui.control.RadioButton
        DefaultButton                 matlab.ui.control.RadioButton
        TabGroup                      matlab.ui.container.TabGroup
        SignalsTab                    matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        FrequencyresponseTab          matlab.ui.container.Tab
        UIAxes_2                      matlab.ui.control.UIAxes
        EstimationerrorTab            matlab.ui.container.Tab
        UIAxes_3                      matlab.ui.control.UIAxes
        FindfilterButton              matlab.ui.control.Button
        LoadfilterednoiseButton       matlab.ui.control.Button
        GeneratenoisefileButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
    end
    
    properties (Access = private)
        x % The generated noise
        d % Loaded filtered noise
        fsx
        fsd % d's sample rate
        filen
        filefn
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratenoisefileButton
        function GeneratenoisefileButtonPushed(app, event)
            fs=96000;
            noise = wgn( 5*fs , 1 , 1,"linear" );
            app.x = 0.7*noise/max(abs(noise));
            audiowrite("noise_file.wav",app.x,fs);
            app.Label.Text="noise_file.wav was generated in the app's folder";
            t=0:1/fs:length(app.x)/fs-1/fs;
            plot(app.UIAxes,t,app.x)
            set(app.UIAxes.Title,'String','The generated noise');
            set(app.UIAxes.XLabel,'String','Time (seconds)');
            set(app.UIAxes.YLabel,'String','Amplitude');
        end

        % Button pushed function: LoadnoisefileButton
        function LoadnoisefileButtonPushed(app, event)
           [app.filen,path] = uigetfile('*.wav');
            if(app.filen~=0)
                selectedfile = fullfile(path,app.filen);
                [app.x,app.fsx] = audioread(selectedfile);
                app.x = app.x(:,1);
                app.Label.Text="Noise file loaded successfully";
                app.filen=1;
                app.LoadfilterednoiseButton.Enable="on";
            else
                app.Label.Text="Noise file not loaded. Please load the filtered noise";
            end  
        end

        % Button pushed function: LoadfilterednoiseButton
        function LoadfilterednoiseButtonPushed(app, event)
            [app.filefn,path] = uigetfile('*.wav');
            if(app.filefn~=0)
                selectedfile = fullfile(path,app.filefn);
                [app.d,app.fsd] = audioread(selectedfile);
                app.d = app.d(:,1);
                app.Label.Text="Filtered noise loaded successfully";
                app.filefn=1;
            else
                app.Label.Text="Filtered noise not loaded. Please load the filtered noise.";
            end
           
            if app.filen && app.filefn
                if (app.fsd == app.fsx)
                    app.FindfilterButton.Enable="on";
                else
                   app.Label.Text="Check that both noise and filtered noise file have the same sample rate"; 
                end
            else
                app.Label.Text="Please load both noise and filered noise files";
            end
        end

        % Selection changed function: SettingsButtonGroup
        function SettingsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SettingsButtonGroup.SelectedObject;
            if selectedButton.Value == 1
                app.FilterlengthEditField.Enable="on";
                app.RegularizationEditField.Enable="on";
                app.StepsizeEditField.Enable="on";
            else
                app.FilterlengthEditField.Enable="off";
                app.RegularizationEditField.Enable="off";
                app.StepsizeEditField.Enable="off";
            end
        end

        % Button pushed function: FindfilterButton
        function FindfilterButtonPushed(app, event)
            fs=app.fsx;
            stop = 0;
            len = min(length(app.x),length(app.d));
            app.d = app.d(1:len);
            app.x = app.x(1:len);

            L = length(app.x);
            a = app.CustomButton.Value;
            if a
                ord = app.FilterlengthEditField.Value;
                mu = app.StepsizeEditField.Value;
                delta = app.RegularizationEditField.Value;
            else
                ord = fs/30;
                mu = 0.1;
                delta = 20*var(app.x);
            end

            if ord<=100
                app.Label.Text="Filter length too small";
                stop = 1;
            end
            if (mu>2) || (mu<=0)
                app.Label.Text = "Choose Step size greater than 0 and smaller than 2";
                stop = 1;
            end
            if delta<=0
                app.Label.Text = "Choose Regularization greater than 0";
                stop = 1;
            end

            if ~stop
                app.Label.Text = "Identifying the filter... Please wait, this could take a while";
                drawnow;
                xlms = zeros(ord,1);
                            
                Want = zeros(1,ord);
                           
                E=zeros(L,1);
                for i=1:L
                    xlms=[app.x(i);xlms(1:ord-1)];
                    E(i)=app.d(i)-Want*xlms;
                    W=Want+mu/(delta+xlms'*xlms)*xlms'*E(i);
                    Want=W;
                end
                W = W/max(abs(W));
                audiowrite("filter.wav",W,fs);
                app.Label.Text = "The IR of the found filter was saved as filter.wav in the app's folder";
                
                plot(app.UIAxes,W)
                set(app.UIAxes.Title,'String','The normalized impulse response of the found filter');
                set(app.UIAxes.XLabel,'String','Samples');
                set(app.UIAxes.YLabel,'String','Amplitude');

                [h,f] = freqz(W,1,20000-20);
                semilogx(app.UIAxes_2,0.5*fs*f/pi,20*log10(abs(h)))
                maximum = max(20*log10(abs(h)));
                set(app.UIAxes_2.Title,'String','The frequency response of the found filter');
                set(app.UIAxes_2.XLabel,'String','Frequency (Hz)');
                set(app.UIAxes_2.YLabel,'String','Gain (dB)');

                ylim(app.UIAxes_2, [floor(maximum/10)*10-30 floor(maximum/10)*10+10]);
                xlim(app.UIAxes_2, [20 20000]);
                app.UIAxes_2.XTick=[0 100 1000 10000];

                plot(app.UIAxes_3,E)
                % set(app.UIAxes.Title,'String','The impulse response of the found filter');
                % set(app.UIAxes.XLabel,'String','Samples');
                % set(app.UIAxes.YLabel,'String','Amplitude');
            end
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            web("https://youtu.be/g_kGnn5GbQA");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FilteridentificationappUIFigure = uifigure('Visible', 'off');
            app.FilteridentificationappUIFigure.Name = 'Filter identification app';
            app.GeneratenoisefileButton = uibutton(app.FilteridentificationappUIFigure, 'push');
            app.GeneratenoisefileButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratenoisefileButtonPushed, true);
            app.GeneratenoisefileButton.Text = 'Generate noise file';
            app.LoadfilterednoiseButton = uibutton(app.FilteridentificationappUIFigure, 'push');
            app.LoadfilterednoiseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilterednoiseButtonPushed, true);
            app.LoadfilterednoiseButton.Enable = 'off';
            app.LoadfilterednoiseButton.Text = 'Load filtered noise';
            app.FindfilterButton = uibutton(app.FilteridentificationappUIFigure, 'push');
            app.FindfilterButton.ButtonPushedFcn = createCallbackFcn(app, @FindfilterButtonPushed, true);
            app.FindfilterButton.Enable = 'off';
            app.FindfilterButton.Text = 'Find filter';
            app.TabGroup = uitabgroup(app.FilteridentificationappUIFigure);
            app.SignalsTab = uitab(app.TabGroup);
            app.SignalsTab.Title = 'Signals';
            app.UIAxes = uiaxes(app.SignalsTab);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.FrequencyresponseTab = uitab(app.TabGroup);
            app.FrequencyresponseTab.Title = 'Frequency response';
            app.UIAxes_2 = uiaxes(app.FrequencyresponseTab);
            title(app.UIAxes_2, 'Frequency response of the found filter')
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, 'Gain (dB)')
            zlabel(app.UIAxes_2, 'Z')
            app.EstimationerrorTab = uitab(app.TabGroup);
            app.EstimationerrorTab.Title = 'Estimation error';
            app.UIAxes_3 = uiaxes(app.EstimationerrorTab);
            title(app.UIAxes_3, 'Estimation error')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Amplitude')
            zlabel(app.UIAxes_3, 'Z')
            app.SettingsButtonGroup = uibuttongroup(app.FilteridentificationappUIFigure);
            app.SettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SettingsButtonGroupSelectionChanged, true);
            app.SettingsButtonGroup.Title = 'Settings';
            app.DefaultButton = uiradiobutton(app.SettingsButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.CustomButton = uiradiobutton(app.SettingsButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.StepsizeEditFieldLabel = uilabel(app.FilteridentificationappUIFigure);
            app.StepsizeEditFieldLabel.Enable = 'off';
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.FilteridentificationappUIFigure, 'numeric');
            app.StepsizeEditField.Enable = 'off';
            app.StepsizeEditField.Value = 0.1;
            app.RegularizationEditFieldLabel = uilabel(app.FilteridentificationappUIFigure);
            app.RegularizationEditFieldLabel.Enable = 'off';
            app.RegularizationEditFieldLabel.Text = 'Regularization';
            app.RegularizationEditField = uieditfield(app.FilteridentificationappUIFigure, 'numeric');
            app.RegularizationEditField.Enable = 'off';
            app.RegularizationEditField.Value = 0.1;
            app.FilterlengthEditField = uieditfield(app.FilteridentificationappUIFigure, 'numeric');
            app.FilterlengthEditField.Enable = 'off';
            app.FilterlengthEditField.Value = 3200;
            app.FilteridentificationusingNormalizedLeastSquaresAlgorithmLabel = uilabel(app.FilteridentificationappUIFigure);
            app.FilteridentificationusingNormalizedLeastSquaresAlgorithmLabel.Text = 'Filter identification using the Normalized Least Mean Squares Algorithm';
            app.FilterlengthEditFieldLabel = uilabel(app.FilteridentificationappUIFigure);
            app.FilterlengthEditFieldLabel.Enable = 'off';
            app.FilterlengthEditFieldLabel.Text = 'Filter length';
            app.Hyperlink = uihyperlink(app.FilteridentificationappUIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.URL = 'https://youtu.be/g_kGnn5GbQA';
            app.Hyperlink.Text = 'Instruction video';
            app.Label = uilabel(app.FilteridentificationappUIFigure);
            app.Label.Text = 'Start by generating the noise file. It will be saved in the app''s folder.';
            app.LoadnoisefileButton = uibutton(app.FilteridentificationappUIFigure, 'push');
            app.LoadnoisefileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadnoisefileButtonPushed, true);
            app.LoadnoisefileButton.Text = 'Load noise file';
            % Show the figure after all components are created
            app.FilteridentificationappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilterIdentificationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FilteridentificationappUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FilteridentificationappUIFigure)
        end
    end
end